classdef bdayParadox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Realisations10500Label  matlab.ui.control.Label
        SampleSize2365Label     matlab.ui.control.Label
        resetButton             matlab.ui.control.Button
        runButton               matlab.ui.control.Button
        ProbabilityofaSharedBirthdayLabel  matlab.ui.control.Label
        SampleSizeSpinner       matlab.ui.control.Spinner
        RealisationsSpinner     matlab.ui.control.Spinner
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SampleSizeSpinner
        function SampleSizeSpinnerValueChanged(app, event)
             X = app.SampleSizeSpinner.Value;
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            n = 0:1:365;
            p = 0:0.1:2;
            p = 1-exp(-n.^2/730);

            iterations = 500;
            groupsize = zeros(1,364);
            repdates = 0;
            for k = 1:364
                for m = 1:iterations
                    dategen = randi(365);
                    disp(dategen)
                    if (groupsize == findmatch(dategen))
                        repdates = repdates + 1;
                    end
                end
            end
            disp(repdates);
            prob = repdates/iterations;
            figure(1)
            plot(repdates,prob)
            plot(app.UIAxes,n,p)

        end

        % Value changed function: RealisationsSpinner
        function RealisationsSpinnerValueChanged(app, event)
            Y = app.RealisationsSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'group size')
            ylabel(app.UIAxes, 'probability')
            app.RealisationsSpinner = uispinner(app.UIFigure);
            app.RealisationsSpinner.Limits = [10 500];
            app.RealisationsSpinner.ValueChangedFcn = createCallbackFcn(app, @RealisationsSpinnerValueChanged, true);
            app.RealisationsSpinner.Value = 10;
            app.SampleSizeSpinner = uispinner(app.UIFigure);
            app.SampleSizeSpinner.Limits = [2 365];
            app.SampleSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleSizeSpinnerValueChanged, true);
            app.SampleSizeSpinner.Value = 2;
            app.ProbabilityofaSharedBirthdayLabel = uilabel(app.UIFigure);
            app.ProbabilityofaSharedBirthdayLabel.Text = 'Probability of a Shared Birthday';
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = {'run'; ''};
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.Text = {'reset'; ''};
            app.SampleSize2365Label = uilabel(app.UIFigure);
            app.SampleSize2365Label.Text = 'Sample Size (2-365)';
            app.Realisations10500Label = uilabel(app.UIFigure);
            app.Realisations10500Label.Text = {'Realisations (10-500)'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bdayParadox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end